classdef device_register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        imgAlert                      matlab.ui.control.Image
        lblAlert                      matlab.ui.control.Label
        imgWarning                    matlab.ui.control.Image
        lblWarning                    matlab.ui.control.Label
        Image5                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        DevicesTab                    matlab.ui.container.Tab
        userPanel                     matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        SignedinasLabel               matlab.ui.control.Label
        lblUser                       matlab.ui.control.Label
        btnBackDevice                 matlab.ui.control.Button
        Panel_7                       matlab.ui.container.Panel
        swtGlobalDevices              matlab.ui.control.Switch
        ShowglobaldevicesSwitchLabel  matlab.ui.control.Label
        btnDeleteDevice               matlab.ui.control.Button
        ChooseadeviceLabel            matlab.ui.control.Label
        tbDevices                     matlab.ui.control.Table
        Panel_6                       matlab.ui.container.Panel
        CapturedeviceLabel            matlab.ui.control.Label
        btnRegister                   matlab.ui.control.Button
        txtModel                      matlab.ui.control.EditField
        ModelEditFieldLabel           matlab.ui.control.Label
        txtBrand                      matlab.ui.control.EditField
        BrandEditFieldLabel           matlab.ui.control.Label
        ImagesTab                     matlab.ui.container.Tab
        Spinner                       matlab.ui.control.Image
        SpinnerLabel                  matlab.ui.control.Label
        dpdDevice                     matlab.ui.control.DropDown
        DeviceDropDownLabel           matlab.ui.control.Label
        userPanel_2                   matlab.ui.container.Panel
        Image_2                       matlab.ui.control.Image
        SignedinasLabel_2             matlab.ui.control.Label
        lblUser_2                     matlab.ui.control.Label
        btnHLDImage                   matlab.ui.control.Button
        btnBackImage                  matlab.ui.control.Button
        Panel_11                      matlab.ui.container.Panel
        MetadataLabel                 matlab.ui.control.Label
        tbMetadata                    matlab.ui.control.Table
        Panel_10                      matlab.ui.container.Panel
        SourceImageLabel              matlab.ui.control.Label
        imgSource                     matlab.ui.control.Image
        btnLoadImage                  matlab.ui.control.Button
        Panel_9                       matlab.ui.container.Panel
        swtGlobalFiles                matlab.ui.control.Switch
        ShowglobalfilesLabel          matlab.ui.control.Label
        ManageimagesLabel             matlab.ui.control.Label
        btnDeleteImage                matlab.ui.control.Button
        tbImages                      matlab.ui.control.Table
    end

    properties (Access = private)
        user % User
        idUser % User id
        brand % Phone brand
        model % Phone model
        newDevice % New device Table
        conn % Connection
        query % Prepare query
        devices % Result of devices
        idDSelected % Id of the selected device
        idISelected % Id of the selected image
        queryGetDevice % Statement to get devices
        lastFile % Name of the last file
        dpdIdSelected % Id of the dropdown image selected
        queryGetRImage % Statement to get References Image
        rImages % Reference image
        metadata % Metadata
        queryGetMetadata % Statement to get metadata
        imageC % Image count
        updCount % Result of query to update image count
        pathRefImgSel 
        fileRefImgSel
        rslt
        route
        model_count
        img_count
    end

    methods (Access = private)
        % Function that obtains all the devices from the database and
        % inserts them into tbDevices
        function getDevices(app)
            % Execute the query and get the results in app.devices
            app.devices = fetch(app.conn, app.queryGetDevice);
            % Insert the values of app.devices into tbDevices
            app.tbDevices.Data = app.devices;
            % This cycle repeats the height of the app.device 
            for i = 1:height(app.devices) 
                % Insert Items to dropdown dpdDevice
                app.dpdDevice.Items = strcat(string(app.devices.idDevice.'), ".- ", string(app.devices.brand.'), " ", string(app.devices.model.'));
            end
        end

        % Function that obtains all the Reference Images from the database 
        % and inserts them into tbImages
        function getRImages(app)
            % Execute the query and get the results in app.rImages
            app.rImages = fetch(app.conn, app.queryGetRImage);
            % Insert the values of app.rImages into tbImages
            app.tbImages.Data = app.rImages;
        end

        % Function that obtains all the metadata from the file and
        % inserts them into tbMetadata
        function getMetadata(app)
            % Execute the query and get the results in app.metadata
            app.metadata = fetch(app.conn, app.queryGetMetadata);
            % Insert the values of app.metadata into tbMetadata
            app.tbMetadata.Data = rows2vars(app.metadata).Var1;
        end

        % 
        function validateImgCount(app)
            frst = app.devices.img_count(1);
            scnd = app.devices.img_count(2);

            if frst >= 30
                if scnd >= 30
                    app.lblAlert.Visible = "off";
                    app.lblWarning.Visible = "off";
                    app.imgAlert.Visible = "off";
                    app.imgWarning.Visible = "off";
                else
                    if scnd >= 20
                        app.lblAlert.Visible = "on";
                        app.lblWarning.Visible = "off";
                        app.imgAlert.Visible = "on";
                        app.imgWarning.Visible = "off";
                    else
                        app.lblAlert.Visible = "off";
                        app.lblWarning.Visible = "on";
                        app.imgAlert.Visible = "off";
                        app.imgWarning.Visible = "on";
                    end
                end
            else
                if frst >= 20
                    if scnd >= 20
                        app.lblAlert.Visible = "on";
                        app.lblWarning.Visible = "off";
                        app.imgAlert.Visible = "on";
                        app.imgWarning.Visible = "off";
                    else
                        app.lblAlert.Visible = "off";
                        app.lblWarning.Visible = "on";
                        app.imgAlert.Visible = "off";
                        app.imgWarning.Visible = "on";
                    end
                else
                    app.lblAlert.Visible = "off";
                    app.lblWarning.Visible = "on";
                    app.imgAlert.Visible = "off";
                    app.imgWarning.Visible = "on";
                   
                end
            end
        end

        % Function to update the image count in database 
        function updateImgCount(app)
            % Prepare the statement to get the image count to selected device 
            app.query = strcat("SELECT img_count FROM Device ", ... 
                        "WHERE idDevice = ", string(app.dpdIdSelected));

            % Try get the image count of selected device
            try
                app.imageC = fetch(app.conn, app.query);
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Convert the image count to int and add one
            count = int32(app.imageC.img_count) + 1;
            % Prepare the statement to update the image count of selected
            % device
            app.query = strcat("UPDATE Device SET img_count = ", string(count), ...
                        " WHERE idDevice = ", string(app.dpdIdSelected));
            % Try update the image count
            try
                app.updCount = fetch(app.conn, app.query);
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Show devices
            app.getDevices();
            app.validateImgCount();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            % Get user submission by previous page
            % app.user = user;
            app.user = "root";
            % Show the user in lblUser
            app.lblUser.Text = app.user;
            app.lblUser_2.Text = app.user;
            % Create the connection with database
            app.conn = database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto');
            % Prepare the statement with the username to get idUser
            app.query = strcat("SELECT idUser FROM User ", ...
                                    "WHERE username = '", app.user, "'");
            % Execute the query and get the results in app.idUser
            app.idUser = fetch(app.conn, app.query);
            % Prepare the statement with the idUser to only get User
            % Devices
            app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count, username FROM Device JOIN User ON User.idUser = Device.idUser ", ...
                                    "WHERE Device.idUser = ", string(app.idUser.idUser), " ORDER BY img_count DESC");
            % Call the function getDevices
            app.getDevices();
            app.dpdIdSelected = app.devices.idDevice(1);
            % Prepare the statement with the idUser to only get User
            % Reference Images
            app.queryGetRImage = strcat("SELECT idRImage, file, username FROM ReferenceImage JOIN User on User.idUser = ReferenceImage.idUser " + ...
                                        "WHERE ReferenceImage.idUser = ", string(app.idUser.idUser));
            % Call the function getRImages
            app.getRImages();
            % Import paths
            addpath(strcat(pwd,'\modules\CameraFingerprint'));
            addpath(strcat(pwd,'\modules\ImageCrop'));
            addpath(strcat(pwd,'\modules\PDF'));
            addpath(strcat(pwd,'\modules\HLD'));
            addpath(strcat(pwd,'\modules\Metadata'));

            app.validateImgCount();
        end

        % Cell selection callback: tbDevices
        function tbDevicesCellSelection(app, event)
            % Get the selected index and with that get the id from the
            % app.devices object with the previously obtained index
            app.idDSelected = app.devices.idDevice(event.Indices(1));
        end

        % Button pushed function: btnRegister
        function btnRegisterButtonPushed(app, event)
            % Get brand of txtBrand
            app.brand = app.txtBrand.Value;
            % Get model of txtModel
            app.model = app.txtModel.Value;
            if app.brand ~= ""
                if app.model ~= ""
                    % The try tries to execute all the instructions, in case of
                        % an error it exit and shows the error obtaining it with the
                        % catch
                    try 
                        % Show a msgbox to confirm the write
                        msgbox("Device register succesfull", "Information", "help");
                        % Clean the TextBoxs
                        app.txtBrand.Value = "";
                        app.txtModel.Value = "";
                        % Crear carpeta
                        carp = Nomenclature(app.brand, app.model);
                        dirRlst = dir(strcat("images\ReferenceImage\", string(carp)));
                        app.model_count = height(dirRlst) - 2;
                        if app.model_count < 0 
                            mkdir(strcat("images\ReferenceImage\", string(carp)));
                            app.model_count = 1;
                        else 
                            % solo puede crear 10 carpetas
                            l = strsplit(dirRlst(height(dirRlst)).name, "_");
                            num_carpeta = l(2);
                            app.model_count = str2num(string(num_carpeta)) + 1;
                        end
                        app.route = strcat("images\ReferenceImage\", string(carp), "\", string(carp), "_", string(app.model_count), "\");
                        mkdir(app.route);
                        mkdir(strcat(app.route, "\Crops"));
                        mkdir(strcat(app.route, "\PRNU"));
                        mkdir(strcat(app.route, "\PDF"));
                        mkdir(strcat(app.route, "\HLD"));

                        % Create a new table to insert a new row in Device table in the
                        % database
                        app.newDevice = table(app.idUser.idUser, string(app.brand), string(app.model), 0, string(app.route), str2num(string(app.model_count)), ...
                                        'VariableNames', {'idUser', 'brand', 'model', 'img_count', 'route', 'model_count'});
                        

                        % Try write in database
                        sqlwrite(app.conn, 'Device', app.newDevice);
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                    % Call the function getDevices
                    app.getDevices();
                else
                    msgbox("Please fill the Model", "Warning", "warn");
                end
            else
                msgbox("Please fill the Brand", "Warning", "warn");
            end
        end

        % Button pushed function: btnDeleteDevice
        function btnDeleteDeviceButtonPushed(app, event)
            % Prepare the statement with the idDevice to delete an device
            app.query = strcat("DELETE FROM Device ", ...
                                    "WHERE idDevice = ", string(app.idDSelected));
             % The try tries to execute all the instructions, in case of
            % an error it exit and shows the error obtaining it with the
            % catch
            try
                % Execute the query and get the results in app.devices
                app.devices = fetch(app.conn, app.query);
            catch e
                % Show a msgbox to show the error
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Call the function getDevices 
            app.getDevices();
        end

        % Callback function: btnLoadImage, imgSource
        function btnLoadImageButtonPushed(app, event)
            % Function than obtains the file and the path to images loaded
            [file, path] = uigetfile('*.jpg;', 'Select One or More Files', 'MultiSelect', 'on');
            
            if iscell(file)
                %% More files
                for i = 1:width(file)
                    % Activate indicators
                    app.Spinner.Visible = "on";
                    app.SpinnerLabel.Text = strcat("Loading... ", string(i),"/", string(width(file)));
                    app.SpinnerLabel.Visible = "on";
                    
                    % Metadata
                    meta = extractMetadata(string(path), string(file(i)));
                    app.query = strcat("SELECT brand, model, route, model_count, img_count FROM Device WHERE idDevice = ", string(app.dpdIdSelected));
                    try
                        app.rslt = fetch(app.conn, app.query);
                        app.route = app.rslt.route;
                        app.model_count = app.rslt.model_count;
                        app.model = app.rslt.model;
                        app.brand = app.rslt.brand;
                        app.img_count = app.rslt.img_count;
                    catch e 
                        msgbox(strcat("Error en la BD: ", char(e.message)), "Warning", "error");
                    end

                    carpEsp = strsplit(string(app.route), "\");
                    lastCarp = carpEsp(length(carpEsp) - 1);
                    imCount = str2num(string(app.img_count)) + 1;
                    % Move image to folder 
                    copyfile(strcat(string(path), string(file(i))), strcat(string(app.route), string(lastCarp), "_(", string(imCount), ").jpg"));
                    
                    newRImage = table(str2num(string(app.dpdIdSelected)), str2num(string(app.idUser.idUser)), strcat(string(lastCarp), "_(", string(imCount), ").jpg"), string(app.route), string(meta.des), string(meta.orig), string(meta.cam), string(meta.adv_photo), string(meta.gps), string(meta.arch), ...
                                    'VariableNames', {'idDevice' 'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                    try
                        % Try write in database
                        sqlwrite(app.conn, 'ReferenceImage', newRImage);
                        app.updateImgCount();
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                end
                msgbox("Reference Images register succesfully", "Information", "help");
                
                app.Spinner.Visible = "off";
                app.SpinnerLabel.Text = "Loaded";
                app.SpinnerLabel.Visible = "on";
            else
                if file ~= 0
                    %% One file
                    % Metadata
                    meta = extractMetadata(string(path), string(file));
                    app.query = strcat("SELECT brand, model, route, model_count, img_count FROM Device WHERE idDevice = ", string(app.dpdIdSelected));
                    try
                        app.rslt = fetch(app.conn, app.query);
                        app.route = app.rslt.route;
                        app.model_count = app.rslt.model_count;
                        app.model = app.rslt.model;
                        app.brand = app.rslt.brand;
                        app.img_count = app.rslt.img_count;
                    catch e 
                        msgbox(strcat("Error en la BD: ", char(e.message)), "Warning", "error");
                    end
    
                    carpEsp = strsplit(string(app.route), "\");
                    lastCarp = carpEsp(length(carpEsp) - 1);
                    imCount = str2num(string(app.img_count)) + 1;
                    % Move image to folder 
                    copyfile(strcat(string(path), string(file)), strcat(string(app.route), "\", string(lastCarp), "_(", string(imCount), ").jpg"));
                    
                    newRImage = table(str2num(string(app.dpdIdSelected)), str2num(string(app.idUser.idUser)), strcat(string(lastCarp), "_(", string(imCount), ").jpg"), string(app.route), string(meta.des), string(meta.orig), string(meta.cam), string(meta.adv_photo), string(meta.gps), string(meta.arch), ...
                                    'VariableNames', {'idDevice' 'idUser' 'file' 'path' 'des' 'orig' 'cam' 'adv_photo' 'gps' 'arch'});
                    try
                        % Try write in database
                        sqlwrite(app.conn, 'ReferenceImage', newRImage);
                        app.updateImgCount();
                        % Show a msgbox to confirm the write
                        msgbox("Reference Image register succesfully", "Information", "help");
                    catch e
                        % Show a msgbox to show the error
                        msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
                    end
                end
            end
            % Get the references Image
            app.getRImages();
        end

        % Button pushed function: btnBackDevice, btnBackImage
        function btnBackDeviceButtonPushed(app, event)
            % Return to admin_view form and send the parameter app.user
            admin_view(app.user);
            % Close this form
            delete(app);
        end

        % Value changed function: swtGlobalDevices
        function swtGlobalDevicesValueChanged(app, event)
            % Check the switch Global Devices value
            if string(app.swtGlobalDevices.Value) == "On"
                % Prepare the statement to get all User Devices
                app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count, username FROM Device JOIN User ON User.idUser = Device.idUser ORDER BY img_count DESC");
            else
                % Prepare the statement with the idUser to only get User
                % Devices
                app.queryGetDevice = strcat("SELECT idDevice, brand, model, img_count, username FROM Device JOIN User ON User.idUser = Device.idUser ", ...
                                    "WHERE Device.idUser = ", string(app.idUser.idUser), " ORDER BY img_count DESC");
            end    
            % Get Devices
            app.getDevices();
        end

        % Value changed function: dpdDevice
        function dpdDeviceValueChanged(app, event)
            % Split the value in the dot and save in temp
            temp = split(app.dpdDevice.Value, ".");
            % Only save the first part of temp and save in propertie
            % dpdIdSelected
            app.dpdIdSelected = str2double(string(temp(1)));
        end

        % Value changed function: swtGlobalFiles
        function swtGlobalFilesValueChanged(app, event)
            % Check the switch Global Files value
            if string(app.swtGlobalFiles.Value) == "On"
                % Prepare the statement to get all User Reference Images
                app.queryGetRImage = strcat("SELECT idRImage, file, username FROM ReferenceImage JOIN User on User.idUser = ReferenceImage.idUser");
            else
                % Prepare the statement with the idUser to only get User
                % Reference Images
                app.queryGetRImage = strcat("SELECT idRImage, file, username FROM ReferenceImage JOIN User on User.idUser = ReferenceImage.idUser ", ...
                                    "WHERE ReferenceImage.idUser = ", string(app.idUser.idUser));
            end    
            % Get References Images
            app.getRImages();
        end

        % Cell selection callback: tbImages
        function tbImagesCellSelection(app, event)
            % Get row selected of the table and only select the id
            app.idISelected = app.rImages.idRImage(event.Indices(1));
            app.query = strcat('SELECT path, file FROM ReferenceImage WHERE idRImage = ', string(app.idISelected));
            try
                app.rslt = fetch(app.conn, app.query);
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            
            % Show the image selected in the ImageSource
            app.imgSource.ImageSource = strcat(string(app.rslt.path), string(app.rslt.file));
            % Prepare the statement with the idRImage to get Metadata from
            % image with these id
            app.queryGetMetadata = strcat("SELECT file, path, des, orig, cam, adv_photo, gps, arch FROM ReferenceImage ", ...
                                "WHERE idRImage = ", string(app.idISelected));
            % Get metadata
            app.getMetadata();
        end

        % Button pushed function: btnHLDImage
        function btnHLDImageButtonPushed(app, event)
            
            % Deactivate table and buttons
            

            % Activate indicators
            app.Spinner.Visible = "on";
            app.SpinnerLabel.Text = "Processing... 1/4";
            app.SpinnerLabel.Visible = "on";

            
            app.imgSource.Enable = "off";
            app.btnLoadImage.Enable = "off";
            app.dpdDevice.Enable = "off";
            app.btnBackDevice.Enable = "off";
            app.btnBackImage.Enable = "off";
            app.btnHLDImage.Enable = "off";
            app.btnDeleteImage.Enable = "off";
            app.btnDeleteDevice.Enable = "off";
            app.tbImages.Enable = "off";
            app.tbDevices.Enable = "off";
            app.btnRegister.Enable = "off";
            app.swtGlobalDevices.Enable = "off";
            app.swtGlobalFiles.Enable = "off";
            % HLD
            % Crop dispute images
            cropAndSaveImg(app.conn, 1, "reference");

            % Extract PRNU from crops
            app.SpinnerLabel.Text = "Processing... 2/4";
            extractPRNU(app.conn, 1, "reference");

            % Extract and save PDF from PRNU in each one
            app.SpinnerLabel.Text = "Processing... 3/4";
            extractPDF(app.conn, 1, "reference");

            % Compare PDFs between reference and dispute
            app.SpinnerLabel.Text = "Processing... 4/4";
            extractAndSaveAreas(app.conn, 1, "reference");
            HLDresult = calculateHLD(app.conn, 1);
            app.SpinnerLabel.Text = "Done";

            % Activate table and buttons
            app.Spinner.Visible = "off";
            app.imgSource.Enable = "on";
            app.btnLoadImage.Enable = "on";
            app.dpdDevice.Enable = "on";
            app.btnBackDevice.Enable = "on";
            app.btnBackImage.Enable = "on";
            app.btnHLDImage.Enable = "on";
            app.btnDeleteImage.Enable = "on";
            app.btnDeleteDevice.Enable = "on";
            app.tbImages.Enable = "on";
            app.tbDevices.Enable = "on";
            app.btnRegister.Enable = "on";
            app.swtGlobalDevices.Enable = "on";
            app.swtGlobalFiles.Enable = "on";
        end

        % Button pushed function: btnDeleteImage
        function btnDeleteImageButtonPushed(app, event)
            % Prepare the statement with the idDevice to delete an
            % Reference Image
            app.query = strcat("DELETE FROM ReferenceImage ", ...
                                    "WHERE idRImage = ", string(app.idISelected));
             % The try tries to execute all the instructions, in case of
            % an error it exit and shows the error obtaining it with the
            % catch
            try
                % Execute the query and get the results in app.devices
                app.rslt = fetch(app.conn, app.query);
            catch e
                % Show a msgbox to show the error
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end
            % Call the function getDevices 
            app.getRImages();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DevicesTab = uitab(app.TabGroup);
            app.DevicesTab.Title = 'Devices';
            app.Panel_6 = uipanel(app.DevicesTab);
            app.BrandEditFieldLabel = uilabel(app.Panel_6);
            app.BrandEditFieldLabel.Text = 'Brand';
            app.txtBrand = uieditfield(app.Panel_6, 'text');
            app.ModelEditFieldLabel = uilabel(app.Panel_6);
            app.ModelEditFieldLabel.Text = 'Model';
            app.txtModel = uieditfield(app.Panel_6, 'text');
            app.btnRegister = uibutton(app.Panel_6, 'push');
            app.btnRegister.ButtonPushedFcn = createCallbackFcn(app, @btnRegisterButtonPushed, true);
            app.btnRegister.Text = 'Register';
            app.CapturedeviceLabel = uilabel(app.Panel_6);
            app.CapturedeviceLabel.Text = 'Capture device';
            app.Panel_7 = uipanel(app.DevicesTab);
            app.tbDevices = uitable(app.Panel_7);
            app.tbDevices.ColumnName = {'idDevice'; 'Brand'; 'Model'; 'Image #'; 'User'};
            app.tbDevices.RowName = {};
            app.tbDevices.CellSelectionCallback = createCallbackFcn(app, @tbDevicesCellSelection, true);
            app.ChooseadeviceLabel = uilabel(app.Panel_7);
            app.ChooseadeviceLabel.Text = 'Choose a device';
            app.btnDeleteDevice = uibutton(app.Panel_7, 'push');
            app.btnDeleteDevice.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteDeviceButtonPushed, true);
            app.btnDeleteDevice.Text = 'Delete';
            app.ShowglobaldevicesSwitchLabel = uilabel(app.Panel_7);
            app.ShowglobaldevicesSwitchLabel.Text = 'Show global devices';
            app.swtGlobalDevices = uiswitch(app.Panel_7, 'slider');
            app.swtGlobalDevices.ValueChangedFcn = createCallbackFcn(app, @swtGlobalDevicesValueChanged, true);
            app.btnBackDevice = uibutton(app.DevicesTab, 'push');
            app.btnBackDevice.ButtonPushedFcn = createCallbackFcn(app, @btnBackDeviceButtonPushed, true);
            app.btnBackDevice.Text = 'Back';
            app.userPanel = uipanel(app.DevicesTab);
            app.lblUser = uilabel(app.userPanel);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.userPanel);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image = uiimage(app.userPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Images';
            app.Panel_9 = uipanel(app.ImagesTab);
            app.tbImages = uitable(app.Panel_9);
            app.tbImages.ColumnName = {'idRImage'; 'File'; 'User'};
            app.tbImages.RowName = {};
            app.tbImages.CellSelectionCallback = createCallbackFcn(app, @tbImagesCellSelection, true);
            app.tbImages.Multiselect = 'off';
            app.btnDeleteImage = uibutton(app.Panel_9, 'push');
            app.btnDeleteImage.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteImageButtonPushed, true);
            app.btnDeleteImage.Text = 'Delete';
            app.ManageimagesLabel = uilabel(app.Panel_9);
            app.ManageimagesLabel.Text = 'Manage images';
            app.ShowglobalfilesLabel = uilabel(app.Panel_9);
            app.ShowglobalfilesLabel.Text = 'Show global files';
            app.swtGlobalFiles = uiswitch(app.Panel_9, 'slider');
            app.swtGlobalFiles.ValueChangedFcn = createCallbackFcn(app, @swtGlobalFilesValueChanged, true);
            app.Panel_10 = uipanel(app.ImagesTab);
            app.btnLoadImage = uibutton(app.Panel_10, 'push');
            app.btnLoadImage.ButtonPushedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.btnLoadImage.Text = 'Load Image';
            app.imgSource = uiimage(app.Panel_10);
            app.imgSource.ImageClickedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.SourceImageLabel = uilabel(app.Panel_10);
            app.SourceImageLabel.Text = 'Source Image';
            app.Panel_11 = uipanel(app.ImagesTab);
            app.tbMetadata = uitable(app.Panel_11);
            app.tbMetadata.ColumnName = {'Data'};
            app.tbMetadata.RowName = {'''Description'';'; '''Source'';'; '''Image'';'; '''Camera'';'; '''Advance photo'';'; '''GPS'';'; '''File'';'};
            app.tbMetadata.Multiselect = 'off';
            app.MetadataLabel = uilabel(app.Panel_11);
            app.MetadataLabel.Text = 'Metadata';
            app.btnBackImage = uibutton(app.ImagesTab, 'push');
            app.btnBackImage.ButtonPushedFcn = createCallbackFcn(app, @btnBackDeviceButtonPushed, true);
            app.btnBackImage.Text = 'Back';
            app.btnHLDImage = uibutton(app.ImagesTab, 'push');
            app.btnHLDImage.ButtonPushedFcn = createCallbackFcn(app, @btnHLDImageButtonPushed, true);
            app.btnHLDImage.Text = 'HLD';
            app.userPanel_2 = uipanel(app.ImagesTab);
            app.lblUser_2 = uilabel(app.userPanel_2);
            app.lblUser_2.Text = 'User Admin';
            app.SignedinasLabel_2 = uilabel(app.userPanel_2);
            app.SignedinasLabel_2.Text = 'Signed in as: ';
            app.Image_2 = uiimage(app.userPanel_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.DeviceDropDownLabel = uilabel(app.ImagesTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.dpdDevice = uidropdown(app.ImagesTab);
            app.dpdDevice.Items = {};
            app.dpdDevice.ValueChangedFcn = createCallbackFcn(app, @dpdDeviceValueChanged, true);
            app.dpdDevice.Value = {};
            app.SpinnerLabel = uilabel(app.ImagesTab);
            app.SpinnerLabel.Visible = 'off';
            app.SpinnerLabel.Text = 'Processing... 1/4';
            app.Spinner = uiimage(app.ImagesTab);
            app.Spinner.Visible = 'off';
            app.Spinner.ImageSource = fullfile(pathToMLAPP, 'src', 'Spinner.gif');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.lblWarning = uilabel(app.UIFigure);
            app.lblWarning.Text = 'It is necessary have at least 20 reference images on 2 devices to have better fidelity';
            app.imgWarning = uiimage(app.UIFigure);
            app.imgWarning.ImageSource = fullfile(pathToMLAPP, 'src', 'warning.png');
            app.lblAlert = uilabel(app.UIFigure);
            app.lblAlert.Text = 'To obtain a better result it is recommended to have at least 30 reference images per device';
            app.imgAlert = uiimage(app.UIFigure);
            app.imgAlert.ImageSource = fullfile(pathToMLAPP, 'src', 'alert.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = device_register(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end