classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figLogin                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        txtPassword             matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        txtUser                 matlab.ui.control.EditField
        UserEditFieldLabel      matlab.ui.control.Label
        btnLogin                matlab.ui.control.Button
        lblDescription          matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        lblWelcome              matlab.ui.control.Label
        imgUTSJR                matlab.ui.control.Image
        imgCICATA               matlab.ui.control.Image
        lblVersion              matlab.ui.control.Label
    end

    
    properties (Access = private)
        conn % Connection DB
        user % Username
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create the connection with the ODBC connector previously created database in "databaseExplorer"
            app.conn = database('conn_scih', 'root', 'root');
        end

        % Button pushed function: btnLogin
        function btnLoginButtonPushed(app, event)
            % Sets to app.user property the value by txtUser
            app.user = app.txtUser.Value;
            if app.user ~= ""
                if app.txtPassword.Value ~= ""
                    % Prepare query statement with user condition
                    query = strcat("SELECT password, type FROM User ", ...
                        "WHERE username = '", app.user, "'");
                    % Execute fetch with the connection and query
                    try
                        rslt = fetch(app.conn, query);
                    catch e
                        msgbox(strcat("Error", char(e.message)), "Warnign", "error");
                    end
                    if height(rslt.password) == 1
                        % Password check in DB with password typed by user
                        if string(rslt.password) == string(app.txtPassword.Value) 
                            % User type check
                            switch string(rslt.type)
                                case "admin"
                                    % 
                                    admin_view(app.user); 
                                    delete(app);
                                case "basic"
                                    % 
                                    basic_view(app.user); 
                                    delete(app);
                            end
                        else
                            % Display a message indicating that an incorrect password
                            % was entered
                            msgbox("Incorrect Password", "Error", "error");
                        end
                    else
                        % Display a message indicating that the user doesn't exist
                        msgbox("This user doesn't exist", "Error", "error");
                    end
                else
                    msgbox("Please fill the TextBox Password", "Warning", "warn");
                end
            else
                msgbox("Please fill the TextBox Username", "Warning", "warn");
            end
            
            
        end

        % Callback function
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: txtPassword
        function txtPasswordValueChanged(app, event)
            value = app.txtPassword.Value;
            % aki pon el metodo pa esconder contraseña plis mike
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figLogin = uifigure('Visible', 'off');
            app.figLogin.Name = 'Login';
            app.lblVersion = uilabel(app.figLogin);
            app.lblVersion.Text = 'V1.0 10/11/2022';
            app.imgCICATA = uiimage(app.figLogin);
            app.imgCICATA.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.imgUTSJR = uiimage(app.figLogin);
            app.imgUTSJR.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Panel = uipanel(app.figLogin);
            app.lblWelcome = uilabel(app.Panel);
            app.lblWelcome.Text = 'Welcome';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.lblDescription = uilabel(app.Panel);
            app.lblDescription.Text = 'Source camera identification by Hellinger distance';
            app.btnLogin = uibutton(app.Panel, 'push');
            app.btnLogin.ButtonPushedFcn = createCallbackFcn(app, @btnLoginButtonPushed, true);
            app.btnLogin.Text = 'Log into my account';
            app.UserEditFieldLabel = uilabel(app.Panel);
            app.UserEditFieldLabel.Text = 'User';
            app.txtUser = uieditfield(app.Panel, 'text');
            app.txtUser.Placeholder = 'User';
            app.PasswordEditFieldLabel = uilabel(app.Panel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.txtPassword = uieditfield(app.Panel, 'text');
            app.txtPassword.ValueChangedFcn = createCallbackFcn(app, @txtPasswordValueChanged, true);
            app.txtPassword.Placeholder = 'Password';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-basic.png');
            app.Image3 = uiimage(app.Panel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-password.png');
            % Show the figure after all components are created
            app.figLogin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figLogin)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figLogin)
        end
    end
end