classdef user_register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_3                 matlab.ui.container.Panel
        PasswordmustcontainuppercaselettersLabel  matlab.ui.control.Label
        PasswordmustcontainatleastonenumberLabel  matlab.ui.control.Label
        Passwordmustbegreaterthan8charactersLabel  matlab.ui.control.Label
        Usernamemustbegreaterthan5charactersLabel  matlab.ui.control.Label
        RulesLabel              matlab.ui.control.Label
        SourcecameraidentificationbyHellingerdistanceLabel  matlab.ui.control.Label
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        btnBack                 matlab.ui.control.Button
        btnHDL                  matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        EmailEditFieldLabel_2   matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        Image5                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        SignedinasLabel         matlab.ui.control.Label
        lblUser                 matlab.ui.control.Label
        EmailEditFieldLabel     matlab.ui.control.Label
        FullnameEditFieldLabel  matlab.ui.control.Label
        btnRegister             matlab.ui.control.Button
        bgUserType              matlab.ui.container.ButtonGroup
        opBasic                 matlab.ui.control.RadioButton
        opAdmin                 matlab.ui.control.RadioButton
        UsernameEditFieldLabel  matlab.ui.control.Label
        PasswordHTML            matlab.ui.control.HTML
        txtEmail                matlab.ui.control.EditField
        txtUsername             matlab.ui.control.EditField
        txtName                 matlab.ui.control.EditField
        lblTitle                matlab.ui.control.Label
    end

    
    properties (Access = private)
        user % Description
        username % Description
        password % Description
        email % Description
        name % Description
        type % Description
        newUser % Description
        conn % Connector ODBC
        query
        rslt
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            app.user = user;
            app.lblUser.Text  = app.user;
            app.conn = database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto');
        end

        % Button pushed function: btnBack
        function btnBackButtonPushed(app, event)
            admin_view(app.user);
            delete(app);
        end

        % Button pushed function: btnRegister
        function btnRegisterButtonPushed(app, event)
            % declare numbers as strings
            numbers = ["0","1","2","3","4","5","6","7","8","9"];
            uppercases = ["A","B","C","D","E","F","G","H","I","J","K","L","M","N","Ñ","O","P","Q","R","S","T","U","V","X","Y","Z"];
            % true/false, depending on the result of the function contains
            % if password contains any of the values of numbers then tf = 1
            tfN = contains(app.password,numbers);
            tfUC = contains(app.password,uppercases);

            app.username = app.txtUsername.Value;
            disp(app.username);
            app.email = app.txtEmail.Value;
            app.name = app.txtName.Value;
            if app.name ~= ""
                if app.username ~= ""
                    if length(app.username) > 5
                        app.query = strcat("SELECT name FROM User ", ...
                            "WHERE username = '", app.username, "'");
                        app.rslt = fetch(app.conn, app.query);
                        if height(app.rslt.name) ~= 1
                            if app.email ~= ""
                                if contains(app.email, "@") && contains(app.email, ".")
                                    if length(app.password) > 8
                                        if tfN
                                            if tfUC
                                                if length(app.password) > 8
                                                    if app.opAdmin.Value
                                                        app.type = "admin";
                                                    else
                                                        app.type = "basic";
                                                    end

                                                    app.newUser = table(string(app.username), string(app.password), string(app.type), string(app.name), string(app.email), ...
                                                        'VariableNames', {'username', 'password', 'type', 'name', 'email'});
                                                    try
                                                        sqlwrite(app.conn, 'User', app.newUser);
                                                        msgbox("Registered user successfully", "Information", "help");
                                                        app.txtUsername.Value = "";
                                                        app.PasswordHTML.Data = "";
                                                        app.txtEmail.Value = "";
                                                        app.txtName.Value = "";
                                                    catch e
                                                        msgbox(strcat("Error", char(e.message)), "Warning", "error");
                                                    end
                                                else
                                                    msgbox("Password must be greater than 8 characters", "Error", "error");
                                                end
                                            else
                                                msgbox("Password must contain uppercase letters", "Error", "error");

                                            end
                                        else
                                            msgbox("Password must contain at least one number", "Error", "error");

                                        end
                                    else
                                        msgbox("Password must be greater than 8 characters", "Warning", "warn");
                                    end
                                else
                                    msgbox("Please insert a valid email", "Warning", "warn");
                                end
                            else
                                msgbox("No email provided", "Warning", "warn");
                            end
                        else
                            msgbox("The username is being used", "Warning", "warn");
                        end
                    else
                        msgbox("Username must be greater than 5 characters", "Error", "error");
                    end
                else
                    msgbox("No username provided", "Warning", "warn");
                end
            else
                msgbox("Enter your full name", "Warning", "warn");
            end
        end

        % Data changed function: PasswordHTML
        function PasswordHTMLDataChanged(app, event)
            app.password = app.PasswordHTML.Data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.lblTitle = uilabel(app.Panel);
            app.lblTitle.Text = 'Register user';
            app.txtName = uieditfield(app.Panel, 'text');
            app.txtUsername = uieditfield(app.Panel, 'text');
            app.txtEmail = uieditfield(app.Panel, 'text');
            app.PasswordHTML = uihtml(app.Panel);
            app.PasswordHTML.HTMLSource = 'passwordEdit.html';
            app.PasswordHTML.DataChangedFcn = createCallbackFcn(app, @PasswordHTMLDataChanged, true);
            app.UsernameEditFieldLabel = uilabel(app.Panel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.bgUserType = uibuttongroup(app.Panel);
            app.bgUserType.Title = 'User type';
            app.opAdmin = uiradiobutton(app.bgUserType);
            app.opAdmin.Text = 'Admin';
            app.opAdmin.Value = true;
            app.opBasic = uiradiobutton(app.bgUserType);
            app.opBasic.Text = 'Basic';
            app.btnRegister = uibutton(app.Panel, 'push');
            app.btnRegister.ButtonPushedFcn = createCallbackFcn(app, @btnRegisterButtonPushed, true);
            app.btnRegister.Text = 'Register';
            app.FullnameEditFieldLabel = uilabel(app.Panel);
            app.FullnameEditFieldLabel.Text = 'Full name';
            app.EmailEditFieldLabel = uilabel(app.Panel);
            app.EmailEditFieldLabel.Text = 'E-mail';
            app.Panel_2 = uipanel(app.Panel);
            app.lblUser = uilabel(app.Panel_2);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.Panel_2);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image4 = uiimage(app.Panel_2);
            app.Image5 = uiimage(app.Panel_2);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.EmailEditFieldLabel_2 = uilabel(app.Panel);
            app.EmailEditFieldLabel_2.Text = 'Password';
            app.btnHDL = uibutton(app.UIFigure, 'push');
            app.btnHDL.Text = 'HLD';
            app.btnBack = uibutton(app.UIFigure, 'push');
            app.btnBack.ButtonPushedFcn = createCallbackFcn(app, @btnBackButtonPushed, true);
            app.btnBack.Text = 'Back';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            app.SourcecameraidentificationbyHellingerdistanceLabel = uilabel(app.UIFigure);
            app.SourcecameraidentificationbyHellingerdistanceLabel.Text = 'Source camera identification by Hellinger distance';
            app.Panel_3 = uipanel(app.UIFigure);
            app.RulesLabel = uilabel(app.Panel_3);
            app.RulesLabel.Text = 'Rules';
            app.Usernamemustbegreaterthan5charactersLabel = uilabel(app.Panel_3);
            app.Usernamemustbegreaterthan5charactersLabel.Text = '-. Username must be greater than 5 characters';
            app.Passwordmustbegreaterthan8charactersLabel = uilabel(app.Panel_3);
            app.Passwordmustbegreaterthan8charactersLabel.Text = '-. Password must be greater than 8 characters';
            app.PasswordmustcontainatleastonenumberLabel = uilabel(app.Panel_3);
            app.PasswordmustcontainatleastonenumberLabel.Text = '-. Password must contain at least one number';
            app.PasswordmustcontainuppercaselettersLabel = uilabel(app.Panel_3);
            app.PasswordmustcontainuppercaselettersLabel.Text = '-. Password must contain uppercase letters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = user_register(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end