classdef view_pdf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image2           matlab.ui.control.Image
        Image4           matlab.ui.control.Image
        Image3           matlab.ui.control.Image
        btnBack          matlab.ui.control.Button
        Panel            matlab.ui.container.Panel
        lblFile          matlab.ui.control.Label
        lblType          matlab.ui.control.Label
        swtImages        matlab.ui.control.Switch
        SHOWSwitchLabel  matlab.ui.control.Label
        tbPDF            matlab.ui.control.Table
        imgShow          matlab.ui.control.Image
        userPanel        matlab.ui.container.Panel
        Image            matlab.ui.control.Image
        SignedinasLabel  matlab.ui.control.Label
        lblUser          matlab.ui.control.Label
    end

    
    properties (Access = private)
        user % Description
        conn % Connection
        queryPDF % Query to get PDF's
        rsltPdf
        statImg 
    end
    
    methods (Access = private)
        % Function that 
        function updateTable(app)
            % Execute the query and get the results in app.devices
            app.rsltPdf = fetch(app.conn, app.queryPDF);
            % Insert the values of app.devices into tbDevices
            app.tbPDF.Data = app.rsltPdf;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, user)
            % Save the parameter user in the propertie app.user
            app.user = "root";
            % Show the user in the label
            app.lblUser.Text = app.user;
            % Create the connection with database
            app.conn = database('conn_scih', 'uqrpef8uvj0gk5k7', 'hS0C86ybuxlTFNVYSSto');
            app.queryPDF = "SELECT * FROM RefPDF";
            app.statImg = "RefPDF";
            app.updateTable();
        end

        % Button pushed function: btnBack
        function btnBackButtonPushed(app, event)
            % Return to login form
            admin_view(app.user);
            % Close this form
            delete(app);
        end

        % Value changed function: swtImages
        function swtImagesValueChanged(app, event)
            disp(app.swtImages.Value)
            % Check the switch 
            if string(app.swtImages.Value) == "Reference Images"
                % Prepare the statement to get
                app.queryPDF = "SELECT * FROM RefPDF";
                app.lblType.Text = "Reference PDF's";
                app.statImg = "RefPDF";
            else
                % Prepare the statement 
                app.queryPDF = "SELECT * FROM DisPDF";
                app.lblType.Text = "Dispute PDF's";
                app.statImg = "DisPDF";
            end    
            % 
            app.updateTable();
        end

        % Cell selection callback: tbPDF
        function tbPDFCellSelection(app, event)
            % Get row selected of the table and only select the id
            idISelected = app.rsltPdf.idPDF(event.Indices(1));
            % Prepare the statement to get path and file of the image by
            % the ID
            query = strcat("SELECT path, file FROM ", app.statImg, " WHERE idPDF = ", string(idISelected));
            try
                rslt = fetch(app.conn, query);
            catch e
                msgbox(strcat("Error: ", char(e.message)), "Warning", "error");
            end

            % Show the image selected in the ImageSource
            disp(strcat(string(rslt.path), string(rslt.file)))
            app.imgShow.ImageSource = strcat(string(rslt.path), string(rslt.file));
            app.lblFile.Text = string(rslt.file);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.userPanel = uipanel(app.Panel);
            app.lblUser = uilabel(app.userPanel);
            app.lblUser.Text = 'User Admin';
            app.SignedinasLabel = uilabel(app.userPanel);
            app.SignedinasLabel.Text = 'Signed in as: ';
            app.Image = uiimage(app.userPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'src', 'icon-admin.png');
            app.imgShow = uiimage(app.Panel);
            app.tbPDF = uitable(app.Panel);
            app.tbPDF.ColumnName = {'idPDF'; 'idImage'; 'File'; 'Path'};
            app.tbPDF.RowName = {};
            app.tbPDF.CellSelectionCallback = createCallbackFcn(app, @tbPDFCellSelection, true);
            app.SHOWSwitchLabel = uilabel(app.Panel);
            app.SHOWSwitchLabel.Text = 'SHOW';
            app.swtImages = uiswitch(app.Panel, 'slider');
            app.swtImages.Items = {'Reference Images', 'Dispute Images'};
            app.swtImages.ValueChangedFcn = createCallbackFcn(app, @swtImagesValueChanged, true);
            app.swtImages.Value = 'Reference Images';
            app.lblType = uilabel(app.Panel);
            app.lblType.Text = 'Reference PDF''s';
            app.lblFile = uilabel(app.Panel);
            app.lblFile.Text = '';
            app.btnBack = uibutton(app.UIFigure, 'push');
            app.btnBack.ButtonPushedFcn = createCallbackFcn(app, @btnBackButtonPushed, true);
            app.btnBack.Text = 'Back';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'src', 'utsjr_icon.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'src', 'cicata_icon.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'src', 'SCI-H.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view_pdf(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end